classdef Recon_3I_Lattice_3DSIM2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        sizeTSpinner             matlab.ui.control.Spinner
        sizeTSpinnerLabel        matlab.ui.control.Label
        sizeZSpinner             matlab.ui.control.Spinner
        sizeZSpinnerLabel        matlab.ui.control.Label
        w2Spinner                matlab.ui.control.Spinner
        w2SpinnerLabel           matlab.ui.control.Label
        w1Spinner                matlab.ui.control.Spinner
        w1SpinnerLabel           matlab.ui.control.Label
        DarkSpinner              matlab.ui.control.Spinner
        DarkSpinnerLabel         matlab.ui.control.Label
        RLSpinner                matlab.ui.control.Spinner
        RLSpinnerLabel           matlab.ui.control.Label
        attWidthSpinner          matlab.ui.control.Spinner
        attWidthSpinnerLabel     matlab.ui.control.Label
        attStrengthSpinner       matlab.ui.control.Spinner
        attStrengthSpinnerLabel  matlab.ui.control.Label
        StartReconButton         matlab.ui.control.Button
        ReadMeTextArea           matlab.ui.control.TextArea
        ReadMeTextAreaLabel      matlab.ui.control.Label
        DataEditField            matlab.ui.control.EditField
        DataEditFieldLabel       matlab.ui.control.Label
        chooseButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            [filename, filepath] = uigetfile;
            app.DataEditField.Value= fullfile(filepath, filename) ;
        end

        % Button pushed function: StartReconButton
        function StartReconButtonPushed(app, event)
            app.StartReconButton.Text='Processing';
            logFile = '.\debug3D3ISIM.txt';
            diary(logFile);
            try
                run3I3DSIMrecon(app);
                disp('Reconstruction completed successfully!');
                app.StartReconButton.Text = 'Recon Done!';
            catch ME
                app.StartReconButton.Text='Error! Please check NVIDIA GPU.';
                fprintf('Error occurred: %s\n', ME.message);
                fprintf('Error in: %s (line %d)\n', ME.stack(1).name, ME.stack(1).line);
                disp('Full error stack:');
                disp(ME.getReport());
                % disp('2')
            end
            diary off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.chooseButton = uibutton(app.UIFigure, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.DataEditFieldLabel = uilabel(app.UIFigure);
            app.DataEditFieldLabel.Text = 'Data ';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.Value = '.\lattice_hex_488_EMTB_mBaojin-P_012\lattice_hex_SEC_013_Channel_488_MMStack_Default.ome.tif';
            app.ReadMeTextAreaLabel = uilabel(app.UIFigure);
            app.ReadMeTextAreaLabel.Text = 'Read Me';
            app.ReadMeTextArea = uitextarea(app.UIFigure);
            app.ReadMeTextArea.Value = {'Please select the folder where the raw data is saved, enter the reconstruction-related parameters, and click "Start Recon" to process all images in the folder.'};
            app.StartReconButton = uibutton(app.UIFigure, 'push');
            app.StartReconButton.ButtonPushedFcn = createCallbackFcn(app, @StartReconButtonPushed, true);
            app.StartReconButton.Text = 'Start Recon';
            app.attStrengthSpinnerLabel = uilabel(app.UIFigure);
            app.attStrengthSpinnerLabel.Text = 'attStrength';
            app.attStrengthSpinner = uispinner(app.UIFigure);
            app.attStrengthSpinner.Value = 0.98;
            app.attWidthSpinnerLabel = uilabel(app.UIFigure);
            app.attWidthSpinnerLabel.Text = 'attWidth';
            app.attWidthSpinner = uispinner(app.UIFigure);
            app.attWidthSpinner.Value = 2;
            app.RLSpinnerLabel = uilabel(app.UIFigure);
            app.RLSpinnerLabel.Text = 'RL';
            app.RLSpinner = uispinner(app.UIFigure);
            app.DarkSpinnerLabel = uilabel(app.UIFigure);
            app.DarkSpinnerLabel.Text = 'Dark';
            app.DarkSpinner = uispinner(app.UIFigure);
            app.w1SpinnerLabel = uilabel(app.UIFigure);
            app.w1SpinnerLabel.Text = 'w1';
            app.w1Spinner = uispinner(app.UIFigure);
            app.w1Spinner.Value = 1;
            app.w2SpinnerLabel = uilabel(app.UIFigure);
            app.w2SpinnerLabel.Text = 'w2';
            app.w2Spinner = uispinner(app.UIFigure);
            app.w2Spinner.Value = 0.9;
            app.sizeZSpinnerLabel = uilabel(app.UIFigure);
            app.sizeZSpinnerLabel.Text = 'sizeZ';
            app.sizeZSpinner = uispinner(app.UIFigure);
            app.sizeZSpinner.Value = 6;
            app.sizeTSpinnerLabel = uilabel(app.UIFigure);
            app.sizeTSpinnerLabel.Text = 'sizeT';
            app.sizeTSpinner = uispinner(app.UIFigure);
            app.sizeTSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recon_3I_Lattice_3DSIM2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end