classdef Recon_Zeiss_3DSIM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartReconButton          matlab.ui.control.Button
        ReadMeTextArea            matlab.ui.control.TextArea
        ReadMeTextAreaLabel       matlab.ui.control.Label
        DataFolderEditField       matlab.ui.control.EditField
        DataFolderEditFieldLabel  matlab.ui.control.Label
        chooseButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            selectedPath = uigetdir;
            app.DataFolderEditField.Value=selectedPath ;
        end

        % Button pushed function: StartReconButton
        function StartReconButtonPushed(app, event)
            app.StartReconButton.Text='Processing';
            logFile = '.\debugZeiss.txt';
            diary(logFile);
            try
                runZeiss3DSIMrecon(app);
                disp('Reconstruction completed successfully!');
                app.StartReconButton.Text = 'Recon Done!';
            catch ME
                app.StartReconButton.Text='Error! Please check NVIDIA GPU.';
                fprintf('Error occurred: %s\n', ME.message);
                fprintf('Error in: %s (line %d)\n', ME.stack(1).name, ME.stack(1).line);
                disp('Full error stack:');
                disp(ME.getReport());
                % disp('2')
            end
            diary off;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.chooseButton = uibutton(app.UIFigure, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.DataFolderEditFieldLabel = uilabel(app.UIFigure);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.UIFigure, 'text');
            app.DataFolderEditField.Value = '.\rawdata\';
            app.ReadMeTextAreaLabel = uilabel(app.UIFigure);
            app.ReadMeTextAreaLabel.Text = 'Read Me';
            app.ReadMeTextArea = uitextarea(app.UIFigure);
            app.ReadMeTextArea.Value = {'Please select the folder containing the .czi files, which should include data acquired in 3D mode using the Zeiss Elyra 7. The wavelength and NA (Numerical Aperture) will be automatically read from the CZI file headers.'};
            app.StartReconButton = uibutton(app.UIFigure, 'push');
            app.StartReconButton.ButtonPushedFcn = createCallbackFcn(app, @StartReconButtonPushed, true);
            app.StartReconButton.Text = 'Start Recon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recon_Zeiss_3DSIM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end